classdef DataApp_final_report_Regressions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        StatusLabel                   matlab.ui.control.Label
        Image_2                       matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        BackButton                    matlab.ui.control.Button
        NextButton                    matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        DataCorrelationTab            matlab.ui.container.Tab
        FilePanel                     matlab.ui.container.Panel
        ShowCorrelationButton         matlab.ui.control.Button
        StatsPanel                    matlab.ui.container.Panel
        PlotAllButton                 matlab.ui.control.Button
        ShowStatsButton               matlab.ui.control.Button
        StatsTable                    matlab.ui.control.Table
        TargetDropDown                matlab.ui.control.DropDown
        TargetLabel                   matlab.ui.control.Label
        LoadFileButton                matlab.ui.control.Button
        VariablesAnalysisTab          matlab.ui.container.Tab
        PlotPanel                     matlab.ui.container.Panel
        PlotTypeDropDown              matlab.ui.control.DropDown
        PlotTypeDropDownLabel         matlab.ui.control.Label
        ImportantVarsDropDown         matlab.ui.control.ListBox
        ImportantVarsLabel            matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        IQR_CleaningTab               matlab.ui.container.Tab
        SelectedVarDropDown           matlab.ui.control.DropDown
        SelectedVarDropDownLabel      matlab.ui.control.Label
        CLEANERButton                 matlab.ui.control.Button
        ApplyOutlierActionsButton     matlab.ui.control.Button
        OutliersUITable_1             matlab.ui.control.Table
        ComputeOutliersButton         matlab.ui.control.Button
        OutliersUIAxes2               matlab.ui.control.UIAxes
        OutliersUIAxes                matlab.ui.control.UIAxes
        Outliers_Detection_LabTab     matlab.ui.container.Tab
        DataQualityIndicator          matlab.ui.container.Panel
        StatusLabel_2                 matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        LampLabel                     matlab.ui.control.Label
        OutlierLabel                  matlab.ui.control.Label
        MethodDropDown                matlab.ui.control.DropDown
        MethodDropDownLabel           matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
        EditFieldLabel                matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        AnalyzeButton                 matlab.ui.control.Button
        VarNameDropDown               matlab.ui.control.DropDown
        VarNameDropDownLabel          matlab.ui.control.Label
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        ML_ModelsTab                  matlab.ui.container.Tab
        ML_Regretion_ModulePanel      matlab.ui.container.Panel
        ML_RegressionLabel            matlab.ui.control.Label
        SelectBestButton              matlab.ui.control.Button
        ExportBestModelButton         matlab.ui.control.Button
        ExportSAVEButton              matlab.ui.control.Button
        TrainButton                   matlab.ui.control.Button
        MetricsTable                  matlab.ui.control.Table
        PredictButton                 matlab.ui.control.Button
        UITable_2                     matlab.ui.control.Table
        ModelDropDown                 matlab.ui.control.DropDown
        ModelDropDownLabel            matlab.ui.control.Label
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        SummaryTab                    matlab.ui.container.Tab
        Summary_Panel                 matlab.ui.container.Panel
        ExportSummaryButton           matlab.ui.control.Button
        VariablesListBox              matlab.ui.control.ListBox
        ListBoxLabel                  matlab.ui.control.Label
        GeneratHeatmapButton          matlab.ui.control.Button
        SummaryUITable                matlab.ui.control.Table
        SummaryCorrelationInsightsLabel  matlab.ui.control.Label
        GenerateSummaryButton         matlab.ui.control.Button
        InsightsRecommendationsTab    matlab.ui.container.Tab
        ExportRecommendationsButton   matlab.ui.control.Button
        RecommendationsLabel          matlab.ui.control.Label
        GeneratRecommendationsButton  matlab.ui.control.Button
        RecommendationsTextArea       matlab.ui.control.TextArea
        InsightsRecommendationsTextAreaLabel  matlab.ui.control.Label
        FinalReportTab                matlab.ui.container.Tab
        ReportTextArea                matlab.ui.control.TextArea
        TextAreaLabel                 matlab.ui.control.Label
        ExportReportButton            matlab.ui.control.Button
        ReportLabel                   matlab.ui.control.Label
        GenerateReportButton          matlab.ui.control.Button
        PredictionNewDataTab          matlab.ui.container.Tab
        PREDICTION_NEW_DATAPanel      matlab.ui.container.Panel
        UITable3                      matlab.ui.control.Table
        UITable2                      matlab.ui.control.Table
        PredictNewDataButton          matlab.ui.control.Button
        ImportTrainedModelButton      matlab.ui.control.Button
        LoadNewDataButton             matlab.ui.control.Button
        UIAxes4                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
     
    % === Raw Data ===
    
    Data                table
    DatasetName
  
    % === Working Data ===
    NumericTable        table      % for outliers work
    
    % === Target & Variables ===
    TargetName          string
    FeatureNames        string
    ImportantVars              % names selected from correlation
    NumericVars        string  % numeric column names
    numVars            double  % count of numeric vars
    
    % ===Cleaning outliers===
     T_original
     T_clean
     varName
     
    % ===ML_Models===========
    % ===ML_REgression Module=
    %------------------------%
       %Data-----
       CorrMatrix       double
       VarNames         cell    
        target
        Xall            table
        yall
        DataML          % cleaned data for ML 
        cv              % partition & split train/test
        sigma
        mu
        
        %Model-----
        Model 
        ModelName
        Xtrain    
        ytrain
        Xtest       
        ytest  
        yPred
        
        %Metics-----
        MAE
        MSE
        RMSE
        R2
        rmseCV
        r2CV
        newRow   
        BestMetrics
        
        %===Best Model------
        BestModel
        BestModelName        string
        BestR2
        BestRMSE
        mdl
        cvmdl
        
        % ==BestModelName==
        currentModelName      
        ModelStore             containers.Map
       
        PredictedValues        double
      
        %--------------------------%
       % Prediction New Data
       NewData
       NewData_Table
       yPred_New
       TrainedModel
       end
    
    properties (Access = public)
    DataTable           table
    SummaryTable 
    
    %Store All Trained Models Results
    TrainModels          table
    Results
    %========%
    DataInfo
    Analysis
    Cleaning
    ML                    % for trained_Models & Best Model
    Insights   
    Recommendations       % for final Report
    Report
    ReportBuffer string   % temprary store for the final Report
    %=========%   
    
   
    end
    
    
   methods (Access = private)
      %--------------------------%                 
      %%  helper Functions : ++++ %%  for Final Report Generation
      %--------------------------% 
      
    function buildFullReport(app)

        app.ReportBuffer = "";              % Reset

        app.addHeader();                    % section no (1)---
        app.addDatasetSection();            % section no (2)---
        app.addModelsSection();             % section no (3)---
        app.addBestModelSection();          % section no (4)---
        app.addRecommendationSection();     % section no (5)---
        app.addFooter();                    % section no (6)---

    end
    
    
   %--------------------------%                 
      %%  helper Functions : ++++ %%  for Final Report Generation
   %--------------------------%  
   % section no (1)---
    function addHeader(app)

app.ReportBuffer = app.ReportBuffer + ...
"FINAL ANALYTICS REPORT" + newline + ...
"====================================" + newline + ...
"Generated on: " + string(datetime("now")) + newline + newline;

end
    
    %==============================
     % section no (2)---
    function addDatasetSection(app)

nSamples  = height(app.DataTable);
nFeatures = width(app.Xtrain);

app.ReportBuffer = app.ReportBuffer + ...
"DATASET KPIs INFORMATION" + newline + ...
"-------------------" + newline + ...
"Samples: " + string(nSamples) + newline + ...
"Features: " + string(nFeatures) + newline + newline;

end
    
    %==============================
     % section no (3)---
   function addModelsSection(app)
       
%==========================================%
T = app.TrainModels;
[~, idx] = max(T.R2);
app.BestModel = T(idx,:);

% --- Sort by R2 descending ---
T = sortrows(T, "R2", "descend");

bestModelName = string(app.BestModel.ModelName{1});

app.ReportBuffer = app.ReportBuffer + ...
"MODEL PERFORMANCE" + newline + ...
"---------------------------------------------------------------" + newline + ...
sprintf("%-5s %-30s %10s %10s\n", ...
    "Rank", "Model", "RMSE", "R2");
"---------------------------------------------------------------" + newline;

for i = 1:height(T)

  modelName = string(T.ModelName{i});
  rmse = T.RMSE(i);
  r2  = T. R2(i);
   %---------------------------

    % ⭐ Mark Best Model
    if modelName == bestModelName
        modelName = modelName + "  *";
    end

    % --- Format RMSE ---
    if isinf(rmse) || isnan(rmse)|| isinf(rmse)
        rmseStr = "--";
    else
        rmseStr = sprintf("%,.0f", rmse);
    end

    % --- Format R2 ---
    if isinf(r2) || isnan(r2)|| isinf(r2)
        r2Str = "--";
    else
        r2Str = sprintf("%.0f", r2);
    end
    
       
    % ✅ FIX: use rmseStr and r2Str — NOT app.RMSE and app.R2
        line = sprintf("%-5d %-30s %10s %10s\n", ...
            i, char(modelName), rmseStr, r2Str);

        app.ReportBuffer = app.ReportBuffer + string(line);

end

app.ReportBuffer = app.ReportBuffer + ...
"---------------------------------------------------------------------" + newline + newline;
%==============================================%
   end
   
    %==============================
     % section no (4)---
    function addBestModelSection(app)
        
%% --- MODEL KPIs ---     
best = app.BestModel;

app.ReportBuffer = app.ReportBuffer + ...
"BEST MODEL KPIs" + newline + ...
"----------" + newline + ...
sprintf("Model : %s\n", best.("ModelName"){1})  + newline + ...
sprintf("RMSE  : %.0f\n", app.BestModel.RMSE) + newline + ...
sprintf("R2    : %.3f\n", app.BestModel.R2) + newline;
    end
    
        
    %==============================
            
     % section no (5)---
    function addRecommendationSection(app)

val = app.RecommendationsTextArea.Value;

if iscell(val)
    rec = strjoin(val, newline);
else
    rec = string(val);
end

app.ReportBuffer = app.ReportBuffer + ...
"RECOMMENDATIONS" + newline + ...
"---------------" + newline + ...
rec + newline + newline;

end
    
    %==============================
      
     % section no (6)---
    function addFooter(app)

app.ReportBuffer = app.ReportBuffer + ...
"-----------------------------------------" + newline + ...
"End of Report" + newline;

end
    
    %==============================
         
   %-------------------------------%

%===============================================================%
  %%  helper Functions : ++++ %%  for Final Report Export
%===============================================================% 

 function exportTextToPDF(app, textContent, fileName, titleText)

        import mlreportgen.dom.*;

        docPath = fullfile(pwd, fileName);
        doc = Document(docPath, 'pdf');

        % Title
        titlePara = Paragraph(titleText);
        titlePara.Bold = true;
        titlePara.FontSize = '12pt';
        append(doc, titlePara);
        append(doc, Paragraph(' '));

        % Body
        parts = splitlines(string(textContent));
        for i = 1:numel(parts)
            lineText = strtrim(parts(i));
            if ~isempty(lineText)
                p = Paragraph(lineText);
                p.FontSize = '11pt';
                append(doc, p);
                append(doc, Paragraph(' '));
            end
        end

        close(doc);

       open(docPath);
      
       uialert(app.UIFigure, ...
                ['File saved successfully at ', docPath], ...
                'Export Complete');
        end
   
%==============================================%
%===============================================================%
  %%  helper Functions : ++++ %%  for Final Report Export.Doc
 %===============================================================% 
function exportTextToWord(app, reportText, fileName)

import mlreportgen.dom.*

doc = Document(fileName,'docx');

%% =====================================================
% TITLE
%% =====================================================

title = Paragraph('FINAL ANALYTICS REPORT');
title.Bold = true;
title.FontSize = '18pt';
title.HAlign = 'center';
append(doc,title);

append(doc,Paragraph(' '));

%% =====================================================
% DATE
%% =====================================================

datePara = Paragraph(['Generated on: ' datestr(now)]);
datePara.Italic = true;
datePara.HAlign = 'center';
append(doc,datePara);

append(doc,Paragraph(' '));

%% =====================================================
% DATASET KPIs INFORMATION
%% =====================================================

append(doc,Paragraph('DATASET KPIs INFORMATION'));
append(doc,Paragraph(' '));

lines = splitlines(reportText);

for i = 1:length(lines)

    txt = strtrim(lines{i});

    if contains(txt,'Samples') || contains(txt,'Features') 
        append(doc,Paragraph(txt));
    end

end

append(doc,Paragraph(' '));

%% =====================================================
% MODEL PERFORMANCE TABLE
%% =====================================================

% 1️⃣ إضافة عنوان القسم
append(doc, Paragraph('MODEL PERFORMANCE'));
append(doc, Paragraph(' '));

% 2️⃣ تحويل بيانات الـ UITable إلى Table
data = app.MetricsTable.Data;
cols = app.MetricsTable.ColumnName;
T = cell2table(data, 'VariableNames', cols);

% 3️⃣ إضافة الجدول إلى التقرير (دون محاولة استدعاء قيمة معادة)
app.appendMATLABTable(doc, T);  

% 4️⃣ ترك سطر فارغ بعد الجدول
append(doc, Paragraph(' '));

%% =====================================================
% BEST MODEL KPIs
%% =====================================================
append(doc, Paragraph(' '));
append(doc,Paragraph('BEST MODEL KPIs'));
append(doc,Paragraph(' '));

for i = 1:length(lines)

    txt = strtrim(lines{i});
    if startsWith(txt,'Model:') || startsWith(txt,'RMSE') || startsWith(txt,'R2')
    %if contains(txt,'Model:') || contains(txt,'RMSE') || contains(txt,'R2')
        append(doc,Paragraph(txt));
    end

end

append(doc,Paragraph(' '));


%% =====================================================
% SUMMARY TABLE
%% =====================================================

append(doc,Paragraph('SUMMARY TABLE'));
append(doc,Paragraph(' '));

app.appendMATLABTable(doc,app.SummaryTable);

append(doc,Paragraph(' '));

%% =====================================================
% RECOMMENDATIONS
%% =====================================================

append(doc,Paragraph('RECOMMENDATIONS'));
append(doc,Paragraph(' '));

for i = 1:length(lines)

    txt = strtrim(lines{i});

    if contains(txt,'Recommendation') || contains(txt,'correlated') || contains(txt,'variables')
        append(doc,Paragraph(txt));
    end

end

append(doc,Paragraph(' '));

%% =====================================================
% END REPORT
%% =====================================================

append(doc,Paragraph('----------------------------------------'));

endReport = Paragraph('End of Report');
endReport.Italic = true;
append(doc,endReport);

close(doc);

winopen(fileName);

 end
 %==============================================%
 
  %===============================================================%
  %%  helper Functions : دالة تحويل MATLAB Table إلى Word Table
 %===============================================================% 

function appendMATLABTable(~, doc, dataTable)

import mlreportgen.dom.*

[nRows,nCols] = size(dataTable);

% --- Model Performance Table ---
% ✅ FIX: Force table to start on new page
% ✅ FIX: force new page before table using paragraph style
newPage = Paragraph(' ');
newPage.Style = {PageBreakBefore(true)};
append(doc, newPage);

% إنشاء جدول Word
wordTable = Table;
wordTable.Border = 'solid';
wordTable.Width = '100%';

% إضافة العناوين
header = TableRow;

for c = 1:nCols
    entry = TableEntry(Paragraph(dataTable.Properties.VariableNames{c}));
    entry.Style = {Bold(true)};
    append(header,entry);
end

append(wordTable,header);
 
% إضافة البيانات
% Add header row
header = TableRow;
header.Style = {RepeatAsHeaderRow(true)};  % ✅ repeat header on each page

for r = 1:nRows
    row = TableRow;
   

    for c = 1:nCols

        value = dataTable{r,c};

        if isnumeric(value)
            value = num2str(value);
        end

        append(row,TableEntry(Paragraph(string(value))));

    end

    append(wordTable,row);

end

append(doc,wordTable)

end

%----------------------------------------------%
end


%==============================================%

%==============================================%

  methods (Access = private)
  %-------------------------------%
        
  %-----------------------------------%      
        %%  helper Functions : ++++ %%
  %----------------------------------%      
  % 1-switchTab(app, +1); -->> NEXT
  
 function Next(app, ~)
    tabs = (app.TabGroup.Children); 
    currentIndex = find(app.TabGroup.SelectedTab == tabs);
    if currentIndex < numel(tabs)
        app.TabGroup.SelectedTab = tabs(currentIndex + 1);
    else
        uialert(app.UIFigure, 'You are already at the last tab.', 'Notice');
    end
   
 end
 %----------------------------------%      
  % 2-switchTab(app, +1); <<---  BACK
  
  function  Back(app, ~)
  tabs = (app.TabGroup.Children);
    currentIndex = find(app.TabGroup.SelectedTab == tabs);
    if currentIndex > 1
        app.TabGroup.SelectedTab = tabs(currentIndex - 1);
    else
        uialert(app.UIFigure, 'You are already at the first tab.', 'Notice');
    end
  end   
%------------------------------------------------------------------%  
%-----------------%   
 %%  helper Functions : for plot in tab2 
%-----------------%   
function Plotdata(app, xVar, yVar)

    x = double(app.DataTable.(xVar));
    y = double(app.DataTable.(yVar));

    valid = ~(isnan(x) | isnan(y));
    x = x(valid);
    y = y(valid);

    cla(app.UIAxes);
    scatter(app.UIAxes, x, y, 15, 'filled');
    hold(app.UIAxes, 'on');

    if numel(x) > 1
        p = polyfit(x, y, 1);
        yfit = polyval(p, x);
        plot(app.UIAxes, x, yfit, 'r-', 'LineWidth', 1.5);
    end

    title(app.UIAxes, sprintf('%s vs %s', yVar, xVar), 'Interpreter', 'none');
    grid(app.UIAxes, 'on');
end
%-----------------% 
%=================================================================%
%------------------------------------------------------------------%
   %%  helper Functions : ++++ %% for Data_nalysis
  %----------------------------------%    
     function ShowCorrelation(app)
  %----------------------------------%      
  % Show correlation heatmap and extract important variables

%--- Check data availability ---
if ~isprop(app,'DataTable') || isempty(app.DataTable)
    uialert(app.UIFigure,'Please load the dataset first.','Warning');
    return;
end

%--- Extract numeric variables ---
numericVars = varfun(@isnumeric, app.DataTable, 'OutputFormat','uniform');
numData     = app.DataTable(:, numericVars);
varNames    = numData.Properties.VariableNames;

%--- Compute correlation matrix ---
corrMatrix = corr(table2array(numData), 'Rows','pairwise');

%--- Plot heatmap ---
figure('Name','Correlation Heatmap','NumberTitle','off');
heatmap(varNames, varNames, corrMatrix, ...
    'Colormap', parula, ...
    'ColorLimits', [-1 1]);
title('Correlation Heatmap');

%--- Get target variable ---
targetVar = app.TargetDropDown.Value;

if ~ismember(targetVar, varNames)
    uialert(app.UIFigure,'Selected target variable not found.','Warning');
    return;
end

%--- Correlation with target ---
targetIdx  = strcmp(varNames, targetVar);
targetCorr = corrMatrix(:, targetIdx);

%--- Create sorted table ---
corrTable = table(varNames', targetCorr, ...
    'VariableNames', {'Variable','Correlation'});

corrTable = sortrows(corrTable, 'Correlation','descend');

%--- Select important variables (|corr| >= 0.5) ---
importantVars = corrTable.Variable( ...
    abs(corrTable.Correlation) >= 0.5 );

if isempty(importantVars)
    importantVars = {'No strong correlations'};
end

%--- Update dropdowns ---
app.ImportantVars.Items            = importantVars;
app.ImportantVarsDropDown.Items    = importantVars;
app.ImportantVarsDropDown.Value    = importantVars{1};

 %--- update list box------for summary Tab-----%
    
    app.VariablesListBox.Items=importantVars;
    
 %---------------------------------------------%

%--- Save report to CSV ---
try
    outputFile = fullfile(pwd,'Important_Variables_Report.csv');

    importantData = corrTable( ...
        ismember(corrTable.Variable, importantVars), :);

    targetName = app.TargetDropDown.Value;
    importantData.Target = repmat({targetName}, height(importantData), 1);

    importantData = importantData(:, {'Target','Variable','Correlation'});

    writetable(importantData, outputFile);

    app.StatusLabel.Text = ...
        sprintf('Important variables saved to: %s', outputFile);

catch ME
    app.StatusLabel.Text = ['Error saving CSV: ' ME.message];
end

%--- Success message ---
uialert(app.UIFigure, ...
    'Correlation heatmap displayed and important variables identified.', ...
    'Success');

  %----------------------------------%  
     end
 %==================================================================%    
     
 %-------------------------------------% 
  function PlotAll(app)
 %-------------------------------------%    
       if isempty(app.DataTable)
        uialert(app.UIFigure, 'Please load data first.', 'No Data');
        return;
       end

    targetVar = app.TargetDropDown.Value;

    importantVars = app.ImportantVars.Items;
    
    nVars = numel(importantVars);

    figure('Name', 'Multiple Plots', 'NumberTitle', 'off');
    t = tiledlayout(ceil(nVars/2), 2, 'Padding', 'compact', 'TileSpacing', 'compact');

    for i = 1:nVars
        nexttile;

        xData = double(app.DataTable.(importantVars{i}));
        yData = double(app.DataTable.(targetVar));

        valid = ~(isnan(xData) | isnan(yData));
        xData = xData(valid);
        yData = yData(valid);

        scatter(xData, yData, 10, 'filled'); hold on;

        if numel(xData) > 1
            p = polyfit(xData, yData, 1);
            y_fit = polyval(p, xData);
            plot(xData, y_fit, 'r-', 'LineWidth', 1.5);
        end

        title(sprintf('%s vs %s', targetVar, importantVars{i}), 'Interpreter', 'none');
        grid on;
    end

    title(t, sprintf('All Important Variables vs %s', targetVar), 'FontWeight', 'bold');
end


     %-------------------------------------% 
      
   %%  helper Functions : +++--->>> for Outliers %%
     %-------------------------------------% 
  function T_clean = remove_outliers_IQR(~, T, numericVars)

    rowsToRemove = false(height(T),1);

    for i = 1:length(numericVars)
        col = numericVars{i};

        Q1 = prctile(T.(col),25);
        Q3 = prctile(T.(col),75);
        IQR_value = Q3 - Q1;

        lowerBound = Q1 - 1.5*IQR_value;
        upperBound = Q3 + 1.5*IQR_value;

        rowsToRemove = rowsToRemove | ...
            (T.(col) < lowerBound | T.(col) > upperBound);
    end

    T_clean = T(~rowsToRemove,:);
    
  end
 
 %-------------------------------------% 

 %-------------------------------------% 
 
  %%  helper Functions : +++--->>> for Outliers %%
%---------------------------------------------%
function [idxOut, lowerB, upperB] = detectOutliersIQR(~, x)
%---------------------------------------------%
    x = x(~isnan(x));

    Q1 = prctile(x,25);
    Q3 = prctile(x,75);
    IQR = Q3 - Q1;

    lowerB = Q1 - 1.5*IQR;
    upperB = Q3 + 1.5*IQR;

    idxOut = (x < lowerB) | (x > upperB);
end

 %-------------------------------------% 
 %---------------------------------------------%
function plotOutliers(app, varName)
%---------------------------------------------%
cla(app.OutliersUIAxes, 'reset');    

x = app.DataTable.(varName);
% force numeric              
    x = double(x);
    x = x(~isnan(x));
    
    if isempty(x)
       return
    end
    
boxplot(app.OutliersUIAxes, x);  

title(app.OutliersUIAxes, varName, 'Interpreter','none');
grid(app.OutliersUIAxes,'on');

end

 %-------------------------------------% 
 
  % ========= Helper Functions === ML_Models====
    % ===ML_REgression Module=
    
  %-------------------------------------% 
 %%%%     helper functions_1  %%%%
        
       function buildPredictors(app) 

            app.target = app.TargetDropDown.Value;

            isNum = varfun(@isnumeric, app.DataTable, ...
                           'OutputFormat','uniform');
            vars = app.DataTable.Properties.VariableNames;

            predictors = vars(isNum);
            predictors(strcmp(predictors, app.target)) = [];

            app.Xall = app.DataTable(:, predictors);
            app.yall = app.DataTable.(app.target);

       end
       
        %%%%        helper functions_2  %%%%
    
    function cleanData(app)

        % Combine predictors and target
        app.DataML = [app.Xall table(app.yall)];

        % Remove missing values
        app.DataML = rmmissing(app.DataML);

        % Remove duplicate rows
        app.DataML = unique(app.DataML);

        % Split back
        app.Xall = app.DataML(:, 1:width(app.Xall));
        app.yall = app.DataML{:, end};

    end
       
       
           %%%%      helper functions_3   %%%%
   function createPartition(app)

        n = height(app.Xall);

        if n < 2
            uialert(app.UIFigure, ...
                'Dataset must contain at least 2 rows.', ...
                'Invalid Dataset');
            return;
        end

        % Hold-out partition
        app.cv = cvpartition(n, 'HoldOut', 0.2);  %%[train=0.8 & test=0.2]
        idxTrain = training(app.cv);
        idxTest  = test(app.cv);

        app.Xtrain = app.Xall(idxTrain, :);
        app.ytrain = app.yall(idxTrain);

        app.Xtest  = app.Xall(idxTest, :);
        app.ytest  = app.yall(idxTest);
    %----------------------------------------------%
    % ==============================
% ====== Scaling Section ======
% ==============================

% 1️⃣ نحول Table إلى Matrix
Xtrain_num = table2array(app.Xtrain);
Xtest_num  = table2array(app.Xtest);

% 2️⃣ نحسب mu و sigma من TRAIN فقط
app.mu = mean(Xtrain_num, 1);
app.sigma = std(Xtrain_num, 0, 1);
app.sigma(app.sigma == 0) = 1;

% 3️⃣ نعمل Scaling على الـ Matrix
Xtrain_scaled = (Xtrain_num - app.mu) ./ app.sigma;
Xtest_scaled  = (Xtest_num  - app.mu) ./ app.sigma;

% 4️⃣ نخزن الناتج كمصفوفة (مش Table)
app.Xtrain = Xtrain_scaled;
app.Xtest  = Xtest_scaled;


    %-----------------------------------------------%
   end
   
    %-------------------------------------------%
          %%%%      helper functions_4   %%%% 
       
      function [BestmodelName, bestIdx] = getBestModel(app)

    data = app.MetricsTable.Data;

    if isempty(data)
        uialert(app.UIFigure,'No models evaluated yet.','Info');
        
        BestmodelName = " ";
        bestIdx = [];
        return
    end

    % Columns: {ModelName, RMSE, R2}
    RMSEs = cell2mat(data(:,2));

    [~, bestIdx] = min(RMSEs);

    BestmodelName = data{bestIdx,1};
      end
      
      
  %-------------------------------------% 
 
  % ========= Helper Functions === ML_Models====
    % ===ML_Classifiction Module=
    
  %-------------------------------------% 
  
  %-------------------------------------% 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.DataCorrelationTab.BackgroundColor      = [0.95 0.90 0.95];
           app.VariablesAnalysisTab.BackgroundColor    = [0.85 0.85 1.0];
           app.ML_ModelsTab.BackgroundColor            = [0.96 0.90 0.96]; 
         
 %--------------------------------------------------------------%               

 % === Initialize Export Option DropDown ===%
 %-------------------------------------------%
 
 %--------------------------------------------------------------%
                % ===ML_REgression Module===
    % Initialize table  
    
    app.MetricsTable.ColumnName = ...
    app.TrainModels.Properties.VariableNames; %===>> if we add new Metrics
   
    app.MetricsTable.ColumnName = ...
    {'Model Name','RMSE','MAE','MSE','R²','rmseCV','r2CV', 'Train Time'};
   
    %-------- for Store BestModel ----------------------------------%
     app.ModelStore = containers.Map('KeyType','char','ValueType','any');
    %---------------------------------------------------------------%
 
   %---------------------------->>>>>>>>>>>>>>>       
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
        
    [file, path] = uigetfile('*.csv');
    if isequal(file,0)
        return;
    end
    app.DatasetName = file;
    
    fullpath = fullfile(path, file);
    
    
    % Read data
    app.Data = readtable(fullpath);
    %------------------------>>
     % Copy to working table
      app.DataTable = app.Data;
    %------------------------>>
     % Copy to Cleaning module
     app.T_original = app.Data;
    %------------------------>>
   %------------------------>>
     
     % بعد قراءة الملف أو أي معالجة
    numericVars = app.T_original.Properties.VariableNames(varfun(@isnumeric, app.T_original, 'OutputFormat', 'uniform'));
    app.SelectedVarDropDown.Items = numericVars;   % نملأ DropDown
    app.SelectedVarDropDown.Value = numericVars{1}; % القيمة الافتراضية

     %------------------------>>
    % Fill Target dropdown
    vars = app.DataTable.Properties.VariableNames;
    app.TargetDropDown.Items = vars;
    %---------------------------------------------------%
    % Status
    app.StatusLabel.Text = "File loaded successfully";
    %----------------------------------------------------%
    %------------------------>>
    
     
        end

        % Value changed function: TargetDropDown
        function TargetDropDownValueChanged(app, event)
           
    app.TargetName = string(app.TargetDropDown.Value);

    % Separate numeric table for analysis
    app.NumericTable = app.DataTable(:, varfun(@isnumeric, app.DataTable, 'OutputFormat','uniform'));

    % Remove target from features
    app.FeatureNames = string(app.NumericTable.Properties.VariableNames);
    app.FeatureNames(app.FeatureNames == app.TargetName) = [];
         
    app.StatusLabel.Text = "Target selected: " + app.TargetName;
            
        end

        % Button pushed function: ShowStatsButton
        function ShowStatsButtonPushed(app, event)
    
    stats = varfun(@mean, app.NumericTable);
    stats.Properties.RowNames = {'Mean'};

    % Put data
    app.StatsTable.Data = stats;

    % === Important lines ===
    app.StatsTable.ColumnName = stats.Properties.VariableNames;
    app.StatsTable.RowName    = stats.Properties.RowNames;
       
        end

        % Button pushed function: ShowCorrelationButton
        function ShowCorrelationButtonPushed(app, event)
          app.ShowCorrelation();

        end

        % Button pushed function: PlotAllButton
        function PlotAllButtonPushed(app, event)
            app.PlotAll();
            
        end

        % Value changed function: ImportantVarsDropDown
        function ImportantVarsDropDownValueChanged(app, event)
     
    xVar = app.ImportantVarsDropDown.Value;
    yVar = app.TargetDropDown.Value;
    %---------------------%    
    app.Plotdata(xVar, yVar);
    %---------------------%   
    
   
  % fill List Box with important variables for summary_Tap: 
    
  app.VariablesListBox.Items=app.ImportantVars.Items;    
  %app.VariablesListBox.Value=app.ImportantVars.value;
   
                
        end

        % Value changed function: PlotTypeDropDown
        function PlotTypeDropDownValueChanged(app, event)
   
    %---------------------- 
      % === chech the Data is found === 
    if isempty(app.DataTable)
        uialert(app.UIFigure,'No Data');
        return;
    end

    targetVar = app.TargetName;
    
    selectedVarIdx = app.ImportantVarsDropDown.Value;  % == selected var from the list ==
    if isempty(selectedVarIdx)
        uialert(app.UIFigure, ' please select variable from list.', 'Missing Selection');
        return;
    end

    app.varName = selectedVarIdx;
    plotType = app.PlotTypeDropDown.Value;

    % Get Data ====
    xData = double(app.DataTable.(app.varName));
    yData = double(app.DataTable.(targetVar));

    %==== Clear Axes before plot====
    cla(app.UIAxes, 'reset');

    % == plot accordeing selected plot type 
    switch plotType
        case 'Scatter'
            scatter(app.UIAxes, xData, yData, 10, 'filled'); hold(app.UIAxes, 'on');
            if numel(xData) > 1
                p = polyfit(xData, yData, 1);
                y_fit = polyval(p, xData);
                plot(app.UIAxes, xData, y_fit, 'r-', 'LineWidth', 1.5);
            end
            hold(app.UIAxes, 'off');
            xlabel(app.UIAxes, app.varName);
            ylabel(app.UIAxes, targetVar);

        case 'Histogram'
            histogram(app.UIAxes, xData);
            xlabel(app.UIAxes, app.varName);

        case 'Boxplot'
            boxchart(app.UIAxes, xData, yData);
            xlabel(app.UIAxes, app.varName);
            ylabel(app.UIAxes, targetVar);
    end

    % === set Tiltle and Grid ===
    title(app.UIAxes, sprintf('Relation: %s vs %s', targetVar, app.varName), 'Interpreter', 'none');
    grid(app.UIAxes, 'on'); 
     
     %----------------------- 
        end

        % Button pushed function: ComputeOutliersButton
        function ComputeOutliersButtonPushed(app, event)

%------------------------------------%
isNum = varfun(@isnumeric, app.DataTable, 'OutputFormat','uniform');
numTable = app.DataTable(:, isNum);
vars = numTable.Properties.VariableNames;
%------------------------------------%
n = length(vars);

varNames  = strings(n,1);
outCounts = zeros(n,1);
lowerB    = zeros(n,1);
upperB    = zeros(n,1);
actions = repmat({'Keep'}, n, 1);
%------------------------------------%
% Loop--------->>>>>>>
for i = 1:n
    x = double(numTable.(vars{i}));

    Q1 = prctile(x,25);
    Q3 = prctile(x,75);
    IQR = Q3 - Q1;

    lb = Q1 - 1.5*IQR;
    ub = Q3 + 1.5*IQR;

    outlierIdx = x < lb | x > ub;

    varNames(i)  = vars{i};
    outCounts(i) = sum(outlierIdx);
    lowerB(i)    = lb;
    upperB(i)    = ub;
end

% Loop--------->>>>>>>
%------------------%
resultTable = table(varNames, outCounts, lowerB, upperB, actions, ...
    'VariableNames', {'Variable','OutliersCount','LowerBound','UpperBound','Action'});
%------------------%
actions = repmat({'Keep'}, height(resultTable), 1);
resultTable.Action = actions;
%------------------%
app.OutliersUITable_1.Data = resultTable;    %  مهم جدا لو مسحته الجدول يبقى فاضى
app.OutliersUITable_1.ColumnName = resultTable.Properties.VariableNames;
app.OutliersUITable_1.ColumnEditable = [false false false false true];
%-------------------------%
% رسم أول متغير تلقائي
app.plotOutliers(vars{1});
%-------------------------%  

%-------------------------------------------------------% 
        end

        % Cell selection callback: OutliersUITable_1
        function OutliersUITable_1CellSelection(app, event)
     %-----------------% 
         
    if isempty(event.Indices)
        return;
    end

    % اسم المتغير من العمود الأول
    row = event.Indices(1);
    app.varName = app.OutliersUITable_1.Data{row, 1};

    % البيانات
    app.varName = app.OutliersUITable_1.Data.Variable(event.Indices(1));
    x = app.DataTable.(app.varName);
    x = double(x);
  
    % الرسم
    cla(app.OutliersUIAxes);
    boxchart(app.OutliersUIAxes, x);
    title(app.OutliersUIAxes, ['Outliers View: ' app.varName], 'Interpreter', 'none');
    grid(app.OutliersUIAxes, 'on');
   %-----------------% 
            
        end

        % Button pushed function: ApplyOutlierActionsButton
        function ApplyOutlierActionsButtonPushed(app, event)
     %--------------%
    if isempty(app.OutliersUITable_1.Data)
        uialert(app.UIFigure,'No outlier table found.','Error');
        return;
    end

  %----------------------------->>
    T = app.OutliersUITable_1.Data;   % الجدول اللي فيه Action
    cleanTable = app.DataTable;       % هنشتغل على نسخة من الداتا
  %----------------------------->>
   tbl = app.OutliersUITable_1.Data;

   varsToRemove = tbl.Variable(strcmp(tbl.Action,'Remove'));
    for i = 1:height(T)

        app.varName = T.Variable{i};
        action  = T.Action{i};

        if strcmp(action,'Remove')

            x = cleanTable.(app.varName);

            Q1 = prctile(x,25);
            Q3 = prctile(x,75);
            IQR = Q3 - Q1;

            lower = Q1 - 1.5*IQR;
            upper = Q3 + 1.5*IQR;

            idx = x < lower | x > upper;

            cleanTable(idx,:) = [];   % حذف الصفوف

        end
    end
    
   % ===== التنفيذ الحقيقي =====
  %------------------------------
  app.DataTable(:, varsToRemove) = [];
  
  % Rebuild NumericTable after cleaning
  app.NumericTable = app.DataTable(:, vartype('numeric'));
 
  % تحديث قائمة المتغيرات الحالية
  %----------
  vars = app.NumericTable.Properties.VariableNames;
  %----------
  app.plotOutliers(vars{1});
  %----------
  app.FeatureNames = string(app.NumericTable.Properties.VariableNames);
  %--------- 
  % تحديث الداتا بعد التنظيف
  %------------------------------
  app.DataTable = cleanTable; 
  uialert(app.UIFigure,'Outlier actions applied successfully.','Done');             
  %---------------% 
  % Save it as a CSV file
  %writetable(cleanTable, 'cleaned_house_prices_data.csv', 'WriteRowNames', true);
  %---------------% 
 
%-------------------------------------------------------% 
        end

        % Button pushed function: CLEANERButton
        function CLEANERButtonPushed(app, event)

    % Step 1:  Use all Numerical columns diictely from:  T_original
    numericVars = app.T_original.Properties.VariableNames(varfun(@isnumeric, app.T_original, 'OutputFormat', 'uniform'));

    % Step 2:Apply IQR cleaning
    app.T_clean = app.remove_outliers_IQR(app.T_original, numericVars);

    % Step 3: disp success massage   
    uialert(app.UIFigure, ...
        sprintf('Cleaning completed!\nBefore: %d rows\nAfter: %d rows', ...
        height(app.T_original), height(app.T_clean)), ...
        'Outliers Removed');
    
  %----------------------------------------->>>>>>>
  % Step 4    
  % update plot on the current column in DropDown ; 
  %----------------------------------------->>>>>>>  
 selectedVar = app.SelectedVarDropDown.Value;

cla(app.OutliersUIAxes2);  
boxplot(app.OutliersUIAxes2 ...
    , app.T_clean.(char(selectedVar)));
title(app.OutliersUIAxes2, ['After Cleaning - ' char(selectedVar)]);

 %---------------% 
 % Disp Before \ After
fprintf("Before: %d rows\n", height(app.T_original));
fprintf("After : %d rows\n", height(app.T_clean));
 %---------------% 
 % Save Cleaned Data as a CSV file
 writetable(app.T_clean, 'kc_house_cleaned.csv', 'WriteRowNames', true);
   
 %---------------% 
 % ⭐️ save in  Results
 %----------------------------%
  app.Results.T_Clean = app.OutliersUITable_1;
 %------------------------------------>>>>>>>>
 
        end

        % Value changed function: SelectedVarDropDown
        function SelectedVarDropDownValueChanged(app, event)
 %-1---------------------------------------->>>>>>>>>>>>>>
    selectedVar = app.SelectedVarDropDown.Value;
   %====insure that T_clean is found before plot===
    if isempty(app.T_clean)
        return
    end
    
    cla(app.OutliersUIAxes2);
    boxplot(app.OutliersUIAxes2, app.T_clean.(char(selectedVar)));
    title(app.OutliersUIAxes2, ['After Cleaning - ' char(selectedVar)]); 
%--------------->>>>>>>>>>>>>>
%--------------->>>>>>>>>>>>>>

        end

        % Value changed function: VarNameDropDown
        function VarNameDropDownValueChanged(app, event)
 %------------->>>
 T = app.Data;
  numericVars = T.Properties.VariableNames( ...
    varfun(@isnumeric, T, 'OutputFormat','uniform'));
 app.VarNameDropDown.Items = numericVars;
 app.varName= app.VarNameDropDown.Value;
 %------------->>>
 % assume defult value of :"k=2"
app.EditField.Value = 2;
 %------------->>>       
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
%% 1) Get Selected Variable
%% 2) Compute Statistics
%% 3) Detect Outliers (IQR / Z-score)
%% 4) Plot Histogram + Bounds
%% 5) Highlight Scatter
%% 6) Update Table
%% 7) Update Data Quality Indicator
%---------------->>>>>>              
            
%---------------->>>>>>  
%1) Get Selected Variable
app.varName = app.Data.Properties.VariableNames(varfun(@isnumeric, app.T_original, 'OutputFormat', 'uniform'));
%---------------->>>>>>  

% 2) Compute Statistics
T = app.Data;
app.varName = app.VarNameDropDown.Value;
x = T.(app.varName);
%===========

% 3) Detect Outliers (IQR / Z-score)
%------->>
k = app.EditField.Value;
%------->>
methodTitle = app.MethodDropDown.Value;
%------->>
if contains(methodTitle, 'Z-Score')
app.mu = mean(x);
app.sigma = std(x);
lower = app.mu - k*app.sigma;
upper = app.mu + k*app.sigma;

else
    % ===== IQR Method =====
x = T.(app.varName);
x = x(~isnan(x));

Q1 = prctile(x,25);
Q3 = prctile(x,75);
IQR_value = Q3 - Q1;

lower = Q1 - 1.5 * IQR_value;
upper = Q3 + 1.5 * IQR_value;

outlierMask = (x < lower) | (x > upper);
outliers = x(outlierMask);

app.mu = mean(x);
app.sigma = std(x);

end

infoTxt = sprintf('\\mu=%.2f   \\sigma=%.2f   limits:[%.2f , %.2f]', ...
                   app.mu, app.sigma, lower, upper);

% 4) Plot Histogram + Bounds on UIAxes2 : 
%---------------->>
outlierMask = (x < lower) | (x > upper);
outliers = x(outlierMask);
outTable = array2table(outliers(:));

%---------------->>>>>>  
% show columns name in table
% 6) Update Table
app.UITable.Data = outTable;
app.UITable.ColumnName = {app.varName};   % ← ده المهم
%---------------->>>>>>  
%% ===== plot on UIAxes2 (Histogram + Normal curve)

cla(app.UIAxes2)
histogram(app.UIAxes2, x, 'Normalization','pdf')
hold(app.UIAxes2,'on')

xx = linspace(min(x), max(x), 200);
yy = normpdf(xx, app.mu, app.sigma);
plot(app.UIAxes2, xx, yy, 'LineWidth',2)

xline(app.UIAxes2, lower, '--')
xline(app.UIAxes2, upper, '--')

%----->> %show limits bounds names on plot  :mu, sigma, lower, upper
txt = sprintf('\\mu=%.1f   \\sigma=%.1f   limits:[%.1f , %.1f]', app.mu, app.sigma, lower, upper);
title(app.UIAxes2, [methodTitle ' Detection - ' app.varName newline infoTxt])
hold(app.UIAxes2,'off')
%---------------->>>>>>>>>>
% 5) Highlight Scatter
%% ===== plot on UIAxes3 (scatter)
%---------------->>>>>>>>>>
cla(app.UIAxes3)
hold(app.UIAxes3,'on')

% drow all points
scatter(app.UIAxes3, 1:length(x), x, 15, 'b','filled')

%  drow outliers values
scatter(app.UIAxes3, find(outlierMask), x(outlierMask), ...
        50, 'r','filled')

title(app.UIAxes3, ['Outliers Highlight - ' app.varName])

hold(app.UIAxes3,'off')

%---------------->>>>
% 7) Update Data Quality Indicator
%---------------->>>>
numOut = sum(outlierMask);
percOut = (numOut/length(x))*100;

%---------------->>>>
app.OutlierLabel.Text = ...
    sprintf('Outliers: %d (%.2f%%)', numOut, percOut);
%---------------->>>>
if percOut < 5
    app.Lamp.Color = [0 0.7 0];  %  Green
     statusText = 'Good';
elseif percOut < 10
    app.Lamp.Color = [1 0.8 0];  %  Yellow
    statusText = 'Moderate';
else
    app.Lamp.Color = [1 0 0];    %  Red
     statusText = 'Critical';
end
%---------------->>>>
% update Stats Text
app.StatusLabel_2.Text = ['Status: ' statusText];
app.StatusLabel_2.FontColor = app.Lamp.Color;
%---------------->>>>
%-------------------------------------------------------%
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
      Next(app, event);
      
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
       Back(app, event);   
       
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
             
 %----Retrieve internal functions---( helper functions)-------%    
    app.buildPredictors();
    app.cleanData();
    app.createPartition();
 %------------------------------------------------------------%  
 
 %------------------------------------------------------------%
  try
    % ---- UI feedback (start) ---->>>>>>
app.ModelName = app.ModelDropDown.Value;
app.StatusLabel.Text = ['Training ' app.ModelName ' model...'];
drawnow;

tStart = tic;

%=== Make Trained Models List===
if isempty(app.TrainModels)
    app.TrainModels = table( ...
        string.empty, [], [], [], [], [], [], [], ...
        'VariableNames', ...
        {'ModelName','RMSE','MAE','MSE','R2','rmseCV','r2CV','TrainTime'} );
end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
%----------------------------%
%%% using if & elseif,  else.%
%----------------------------%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%----- 1st Model ---if-statments
%------------------------------%
val = app.ModelDropDown.Value;
%------------------------------%
if contains(val,'Linear Regression','IgnoreCase',true)
   app.mdl = train_LR(app.Xtrain, app.ytrain);
   %========
   app.yPred = predict(app.mdl, app.Xtest);
   %=======
   %===Metrics ===
   app.MSE = mean((app.ytest - app.yPred).^2);
   app.MAE = mean(abs(app.ytest - app.yPred));
   app.RMSE = sqrt(app.MSE);
   app.R2   = 1 - sum((app.ytest - app.yPred).^2) / ...
                sum((app.ytest - mean(app.ytest)).^2);

   TrainTime = toc(tStart);
   %--------------------------------------------%
    %===========================================
     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
     % 3) Cross Validation  (5-Fold) ---
     % Cross-Val metrics (الطريقة الخاصة) 
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
  app.cvmdl = fitrlinear(app.Xtrain, app.ytrain,'KFold',5);
                        %------------------------------%
   yCVPred = kfoldPredict(app.cvmdl);

   app.rmseCV = sqrt(mean((app.ytrain - yCVPred).^2));
   app.r2CV = 1 - sum((app.ytrain - yCVPred).^2) / ...
            sum((app.ytrain - mean(app.ytrain)).^2);
    %===========================================
   %=======
   app.ModelName = 'Linear Regression';
   app.ModelStore(app.ModelName) = app.mdl;
   app.currentModelName = app.ModelName;
   %=======
   %=== newRow List === 
   app.newRow = { ...
    app.ModelName, ...
    app.RMSE, ...
    app.MAE, ...
    app.MSE, ...
    app.R2, ...
    app.rmseCV, ...
    app.r2CV, ...
    TrainTime ...
};
  
   %=======
   %=== UPdate Table
   app.MetricsTable.Data = [app.MetricsTable.Data; app.newRow];
   %----------------------------------------------------------%
   %-------------------->>>>>>>>>
  app.TrainModels = [app.TrainModels; app.newRow];
   %-------------------->>>>>>>>>
   % Store Results of TrainModels                   
   %-------------------->>>>>>>>>> 
                         
   %-------------------->>>>>>>>>> 
%--------------------------------------------------------------%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%----- 2nd  Model ---
%--------------------------------------------------------------%
elseif contains(val,'Random Forest','IgnoreCase',true)
    
   app.mdl = train_RF(app.Xtrain, app.ytrain);
   %=======
   app.yPred = predict(app.mdl, app.Xtest);
   %=======
   %===Metrics ===
   app.MSE = mean((app.ytest - app.yPred).^2);
   app.MAE = mean(abs(app.ytest - app.yPred));
   app.RMSE = sqrt(app.MSE);
   app.R2   = 1 - sum((app.ytest - app.yPred).^2) / ...
                sum((app.ytest - mean(app.ytest)).^2);

   TrainTime = toc(tStart);
  %--------------------------------------------%
    %===========================================
     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
   % 3) Cross Validation  (5-Fold) --- (على التدريب)
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
   app.cvmdl = crossval(app.mdl,'KFold',5);
   yCVPred = kfoldPredict(app.cvmdl);

  app.rmseCV = sqrt(mean((app.ytrain - yCVPred).^2));
  app.r2CV = 1 - sum((app.ytrain - yCVPred).^2) / ...
            sum((app.ytrain - mean(app.ytrain)).^2);

   %============================================================
   %=======
   app.ModelName = 'Random Forest';
   app.ModelStore(app.ModelName) = app.mdl;
   app.currentModelName = app.ModelName;

    %=======
    %=== newRow List === 
   app.newRow = { ...
    app.ModelName, ...
    app.RMSE, ...
    app.MAE, ...
    app.MSE, ...
    app.R2, ...
    app.rmseCV, ...
    app.r2CV, ...
    TrainTime ...
};
  
   %=======
   app.MetricsTable.Data = [app.MetricsTable.Data; app.newRow];
   %----------------------------------------------------------%
   %-------------------->>>>>>>>>
  app.TrainModels = [app.TrainModels; app.newRow];
   %-------------------->>>>>>>>>
   % Store Results of TrainModels                   
   %-------------------->>>>>>>>>> 
                  
   %-------------------->>>>>>>>>> 

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%           
%----- 3rd  Model ---
%--------------------------------------------------------------%  
elseif contains(val,'Support Vector Regression','IgnoreCase',true)
    
   app.mdl = train_SVR(app.Xtrain, app.ytrain);
   %=======
   app.yPred = predict(app.mdl, app.Xtest);
   %=======
   %===Metrics ===
   app.MSE = mean((app.ytest - app.yPred).^2);
   app.MAE = mean(abs(app.ytest - app.yPred));
   app.RMSE = sqrt(app.MSE);
   app.R2   = 1 - sum((app.ytest - app.yPred).^2) / ...
                sum((app.ytest - mean(app.ytest)).^2);

   TrainTime = toc(tStart);
   
   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
   % 3) Cross Validation  (5-Fold) --- (على التدريب)
   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
   app.cvmdl = crossval(app.mdl,'KFold',5);
   yCVPred = kfoldPredict(app.cvmdl);

  app.rmseCV = sqrt(mean((app.ytrain - yCVPred).^2));
  app.r2CV = 1 - sum((app.ytrain - yCVPred).^2) / ...
            sum((app.ytrain - mean(app.ytrain)).^2);

   %============================================================
   %=======
   app.ModelName = 'Support Vector Regression';
   app.ModelStore(app.ModelName) = app.mdl;
   app.currentModelName = app.ModelName;
   %=======
   %=== newRow List === 
   app.newRow = { ...
    app.ModelName, ...
    app.RMSE, ...
    app.MAE, ...
    app.MSE, ...
    app.R2, ...
    app.rmseCV, ...
    app.r2CV, ...
    TrainTime ...
};
  
   %=======
   app.MetricsTable.Data = [app.MetricsTable.Data; app.newRow];
   %----------------------------------------------------------%
   %-------------------->>>>>>>>>
  app.TrainModels = [app.TrainModels; app.newRow];
   %-------------------->>>>>>>>>
   % Store Results of TrainModels                   
   %-------------------->>>>>>>>>> 
                       
   %-------------------->>>>>>>>>> 
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%           
%----- 4th  Model ---
%--------------------------------------------------------------%   
elseif contains(val,'Decision Tree','IgnoreCase',true)
    
   app.mdl = train_DT(app.Xtrain, app.ytrain);
   %=======
   app.yPred = predict(app.mdl, app.Xtest);
   %=======
   %===Metrics ===
   app.MSE = mean((app.ytest - app.yPred).^2);
   app.MAE = mean(abs(app.ytest - app.yPred));
   app.RMSE = sqrt(app.MSE);
   app.R2   = 1 - sum((app.ytest - app.yPred).^2) / ...
                sum((app.ytest - mean(app.ytest)).^2);

   TrainTime = toc(tStart);
   
   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
   % 3) Cross Validation  (5-Fold) --- (على التدريب)
   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
   app.cvmdl = crossval(app.mdl,'KFold',5);
   yCVPred = kfoldPredict(app.cvmdl);

  app.rmseCV = sqrt(mean((app.ytrain - yCVPred).^2));
  app.r2CV = 1 - sum((app.ytrain - yCVPred).^2) / ...
            sum((app.ytrain - mean(app.ytrain)).^2);

   %============================================================
   %=======
   app.ModelName = 'Descision Tree';
   app.ModelStore(app.ModelName) = app.mdl;
   app.currentModelName = app.ModelName;
   %=======
  %=== newRow List === 
   app.newRow = { ...
    app.ModelName, ...
    app.RMSE, ...
    app.MAE, ...
    app.MSE, ...
    app.R2, ...
    app.rmseCV, ...
    app.r2CV, ...
    TrainTime ...
};
  
   %=======
   app.MetricsTable.Data = [app.MetricsTable.Data; app.newRow];
%--------------------------------------------------------------%
%-------------------->>>>>>>>>
  app.TrainModels = [app.TrainModels; app.newRow];
   %-------------------->>>>>>>>>
   % Store Results of TrainModels                   
   %-------------------->>>>>>>>>> 
               
   %-------------------->>>>>>>>>>
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%           
%----- 5th  Model ---
%--------------------------------------------------------------%   
 elseif contains(val,'Gaussian Process Regression','IgnoreCase',true) 

   app.mdl = train_GPR(app.Xtrain, app.ytrain);
   %=======
   app.yPred = predict(app.mdl, app.Xtest);
   %=======
   %===Metrics ===
   app.MSE = mean((app.ytest - app.yPred).^2);
   app.MAE = mean(abs(app.ytest - app.yPred));
   app.RMSE = sqrt(app.MSE);
   app.R2   = 1 - sum((app.ytest - app.yPred).^2) / ...
                sum((app.ytest - mean(app.ytest)).^2);

   TrainTime = toc(tStart);
   
   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
   % 3) Cross Validation  (5-Fold) --- (على التدريب)
   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
   app.cvmdl = crossval(app.mdl,'KFold',5);
   yCVPred = kfoldPredict(app.cvmdl);

  app.rmseCV = sqrt(mean((app.ytrain - yCVPred).^2));
  app.r2CV = 1 - sum((app.ytrain - yCVPred).^2) / ...
            sum((app.ytrain - mean(app.ytrain)).^2);

   %============================================================
   %=======
   app.ModelName = 'Gaussian Process Regression';
   app.ModelStore(app.ModelName) = app.mdl;
   app.currentModelName = app.ModelName;
   %=======
   %=== newRow List === 
   app.newRow = { ...
    app.ModelName, ...
    app.RMSE, ...
    app.MAE, ...
    app.MSE, ...
    app.R2, ...
    app.rmseCV, ...
    app.r2CV, ...
    TrainTime ...
};
  
   %=======
   app.MetricsTable.Data = [app.MetricsTable.Data; app.newRow];  
  %--------------------------------------------------------------% 
  %-------------------->>>>>>>>>
  app.TrainModels = [app.TrainModels; app.newRow];
   %-------------------->>>>>>>>>
   % Store Results of TrainModels                   
   %-------------------->>>>>>>>>> 
                     
   %-------------------->>>>>>>>>>
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%           
%----- 6th  Model ---
%--------------------------------------------------------------%   
 elseif contains(val,'Neural Network','IgnoreCase',true)
     
    app.mdl = train_NN(app.Xtrain, app.ytrain);
   %=======
   app.yPred = predict(app.mdl, app.Xtest);
   %=======
   %===Metrics ===
   app.MSE = mean((app.ytest - app.yPred).^2);
   app.MAE = mean(abs(app.ytest - app.yPred));
   app.RMSE = sqrt(app.MSE);
   app.R2   = 1 - sum((app.ytest - app.yPred).^2) / ...
                sum((app.ytest - mean(app.ytest)).^2);

   TrainTime = toc(tStart);
   
   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
   % 3) Cross Validation  (5-Fold) --- (على التدريب)
   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
   app.cvmdl = crossval(app.mdl,'KFold',5);
   yCVPred = kfoldPredict(app.cvmdl);

  app.rmseCV = sqrt(mean((app.ytrain - yCVPred).^2));
  app.r2CV = 1 - sum((app.ytrain - yCVPred).^2) / ...
            sum((app.ytrain - mean(app.ytrain)).^2);

   %============================================================
   %=======
   
   app.ModelName = 'Neural Network';
   app.ModelStore(app.ModelName) = app.mdl;
   app.currentModelName = app.ModelName;
   %=======
   %=== newRow List === 
   app.newRow = { ...
    app.ModelName, ...
    app.RMSE, ...
    app.MAE, ...
    app.MSE, ...
    app.R2, ...
    app.rmseCV, ...
    app.r2CV, ...
    TrainTime ...
};
  
   %=======
   app.MetricsTable.Data = [app.MetricsTable.Data; app.newRow]; 
 %--------------------------------------------------------------% 
 %-------------------->>>>>>>>>
  app.TrainModels = [app.TrainModels; app.newRow];
   %-------------------->>>>>>>>>
   % Store Results of TrainModels                  
   %-------------------->>>>>>>>>> 
                    
   %-------------------->>>>>>>>>>
   
 else
    uialert(app.UIFigure,'Unknown model selected','Model Error');
    return
end
%-------------------------------------------------------%
app.StatusLabel.Text = 'Training completed';
 catch ME
    app.StatusLabel.Text = 'Training failed';
    disp(ME.message);
 end
%-------------------------------------------------------%
%---------------% 
% ⭐️ === Save TrainModels as csv ====
%------------------------------------%
writetable(app.TrainModels,'TrainModels.csv')
%-------------------------------------------------------%
               
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
     % -------- Prediction --------%
%------feed Back--------Start--------%         
app.StatusLabel.Text = 'Working...';
drawnow;          
%--------------------------------------%       
app.Xtest = app.Xtest;
if istable(app.Xtest)
    app.Xtest = table2array(app.Xtest);
end
Xnew=app.Xtest;
%------NEW------Start--------------------------%      
app.mdl = app.ModelStore(app.currentModelName);
app.yPred = predict(app.mdl, Xnew);     
 
app.PredictedValues = app.yPred;
%------NEW-------END-------------------------%  

%-----------------------------------------------%
% STEP 3: Prediction vs Actual Plot (🔥 visual proof)

cla(app.UIAxes_2)

scatter(app.UIAxes_2, app.ytest, app.yPred, 15, 'filled')
hold(app.UIAxes,'on')

% Ideal line
minVal = min([app.ytest; app.yPred]);
maxVal = max([app.ytest; app.yPred]);
plot(app.UIAxes_2,[minVal maxVal],[minVal maxVal],'r--','LineWidth',1)

hold(app.UIAxes_2 ...
    ,'off')

xlabel(app.UIAxes_2,'Actual Values')
ylabel(app.UIAxes_2,'Predicted Values')
title(app.UIAxes_2,'Prediction vs Actual')
grid(app.UIAxes_2,'on')
%-----------------------------------------------%
%🟢 STEP 4: Status feedback (polish)
app.StatusLabel.Text = 'Prediction & evaluation completed successfully ✔️';
drawnow;
%----------------------------------------------%
app.UITable_2.Data = table(app.yPred, 'VariableNames', {'PredictedPrice'});

%-OR---------------------------------------------%

%plot(app.UIAxes_3, app.yPred, '--o');
%title(app.UIAxes_3, ['Prediction - ' char(app.currentModelName)]);
%----------------------------------------------%

cla(app.UIAxes_3)

scatter(app.UIAxes_3, app.ytest, app.yPred, 15, 'filled')
hold(app.UIAxes_3,'on')

% Ideal line
minVal = min([app.ytest; app.yPred]);
maxVal = max([app.ytest; app.yPred]);
plot(app.UIAxes_3,[minVal maxVal],[minVal maxVal],'-o','LineWidth',1)

hold(app.UIAxes_3,'off')

%xlabel(app.UIAxes_3,'Actual Values')
%ylabel(app.UIAxes_3,'Predicted Values')
title(app.UIAxes_3, ['Prediction - ' char(app.currentModelName)]);
grid(app.UIAxes_3,'on')

%----------------------------------------------%
% app.mdl = app.BestModel;   % وليس current model      %  xxxx
%----------------------------------------------% 
  
%---------------% 
% ⭐ Save Prediction Results

%app.Results.BestModel     = app.ModelStore(app.BestModelName);

app.Results.PredictedValues = app.yPred;

  %---------------->>>>>>>>          
            
        end

        % Button pushed function: ExportSAVEButton
        function ExportSAVEButtonPushed(app, event)
    %------------------------------------------------% 
            data = app.MetricsTable.Data;
    if isempty(data)
        uialert(app.UIFigure,'Nothing to export.','Info');
        return
    end

    T = cell2table(data, ...
    'VariableNames', app.MetricsTable.ColumnName);
    
    %T = cell2table(data, ...
    %    'VariableNames', {'Model Name','RMSE','MAE','MSE','R²','Train Time'});

    [file,path] = uiputfile({'*.xlsx';'*.csv'},'Save Results');
    if isequal(file,0); return; end

    fullpath = fullfile(path,file);

    if endsWith(file,'.csv')
        writetable(T, fullpath);
    else
        writetable(T, fullpath,'FileType','spreadsheet');
    end

    app.StatusLabel.Text = 'Results exported successfully ✔️';
    
    %----------------------------------------------% 
    
    % ⭐️ === Save TrainModels as.mat ====
    
% ✅ FIX: retrieve bestModelObj from ModelStore using BestModel name
    bestName     = app.BestModel.ModelName{1};
    bestModelObj = app.ModelStore(bestName);       % ← was undefined before    
    
    
features = app.FeatureNames;
target   = app.TargetName;
modelType = class(bestModelObj);

save(fileName,...
     'bestModelObj',...
     'features',...
     'target',...
     'modelType');
    %--------------------------------------->>
        end

        % Button pushed function: SelectBestButton
        function SelectBestButtonPushed(app, event)
%===================================================%

     % Check if any models have been trained
    if isempty(app.TrainModels)
        uialert(app.UIFigure, 'No models trained yet.', 'Info');
        return;
    end

    %------------------------------------%
    % Select Best Model by minimum RMSE
    %------------------------------------%
    [~, idx] = min(app.TrainModels.RMSE);
    app.BestModel = app.TrainModels(idx, :);

    %-----------------------------------%
    % ✅ FIX: Store Best Model KPIs as properties
    %-----------------------------------%
    app.BestModelName = app.BestModel.ModelName{1};
    app.BestRMSE      = app.BestModel.RMSE;
    app.BestR2        = app.BestModel.R2;

    %------------------------------------%
    % Show confirmation alert
    %------------------------------------%
    msg = sprintf( ...
        'Best Model : %s\nRMSE       = %.0f\nR²         = %.3f', ...
        app.BestModelName, ...
        app.BestRMSE, ...
        app.BestR2);

    uialert(app.UIFigure, msg, '⭐ Best Model Selected');

    %-----------------------------------------------%
    app.StatusLabel.Text = ['Best Model: ' app.BestModelName ' ✔️'];
    %-----------------------------------------------%

        end

        % Button pushed function: ExportBestModelButton
        function ExportBestModelButtonPushed(app, event)
 %------------------------------------------------------% 
bestName = app.BestModel.ModelName;
bestModelObj = app.ModelStore(bestName);
bestMetrics = app.BestModel;

% ⭐️ features و target  from Training
features = app.FeatureNames;
target   = app.TargetName;

fileName = ['BestModel_' char(bestName) '.mat'];

save(fileName, ...
     'bestModelObj', ...
     'bestMetrics', ...
     'bestName', ...
     'features', ...
     'target');
 
  %------------------------------------------------------%
  [file,path] = uiputfile('*.mat','Save Best Model');

if file ~= 0
    save(fullfile(path,file), ...
         'bestModelObj','bestMetrics','bestName');
end
 %-------------------------------------------------------%
 
        end

        % Value changed function: VariablesListBox
        function VariablesListBoxValueChanged(app, event)
          
    selectedVars = app.ImportantVars.Items;
    % تحديث Tab3 تلقائيًا
    if isprop(app, 'VariablesListBox') && ~isempty(app.VariablesListBox)
        app.VariablesListBox.Items = selectedVars;
    end   
            
        end

        % Button pushed function: GenerateSummaryButton
        function GenerateSummaryButtonPushed(app, event)
            %---------------------------------------------------%
         % تأكد أن البيانات موجودة
    if isempty(app.DataTable)
        uialert(app.UIFigure, 'No data loaded. Please load data first.', 'Missing Data');
        return;
    end

    % استدعاء الدالة التي تحسب الإحصائيات
    app.SummaryTable = generateSummary(app);

    % عرض النتائج داخل جدول SummaryTap
    if ~isempty(app.SummaryUITable)
        app.SummaryUITable.Data = app.SummaryTable;
        app.SummaryUITable.ColumnName = app.SummaryTable.Properties.VariableNames;
        app.SummaryUITable.ColumnWidth = 'auto';
    end

    disp('Summary table displayed successfully.');  
    %--------------------------------------------------------% 
    
     %store results in App.Results:
   %----------------------------%
     app.Results.SummaryTable = app.SummaryTable;
    %----------------------------%
    
    
        end

        % Button pushed function: GeneratHeatmapButton
        function GeneratHeatmapButtonPushed(app, event)
            
            % تأكد أن البيانات موجودة
    if isempty(app.SummaryTable)
        uialert(app.UIFigure, 'Please generate summary first.', 'No Data');
        return;
    end

    % استخراج أسماء المتغيرات
    vars = app.SummaryTable.Variable;

    % إنشاء مصفوفة ارتباط بين أهم المتغيرات
    data = app.DataTable(:, vars);
    corrMatrix = corr(table2array(data), 'Rows', 'complete');

    % رسم الـ Heatmap
   %----------------------------------------------------------------%
     h = heatmap(figure, vars, vars, corrMatrix, ...    %% simple in outside figure
    'Colormap', parula, ...
    'ColorLimits', [-1 1]);    
   %------------------%
     title(h, 'Correlation Heatmap of Important Variables');
   %------------------------------------------------------%
     
    % ⭐️ حفظ في Results
   %----------------------------%
     app.Results.CorrelationMatrix = corrMatrix;
     app.Results.CorrelationVars   = vars;


        end

        % Button pushed function: ExportSummaryButton
        function ExportSummaryButtonPushed(app, event)
     %----------------------------------------------------%         
     
    %----------------------------%
       % رسالة تأكيد
    uialert(app.UIFigure, ' Summary exported successfully as PDF!', 'Export Complete');
    %----------------------------%       
            
    
%----------------------------------------------------%  
    
        end

        % Button pushed function: GeneratRecommendationsButton
        function GeneratRecommendationsButtonPushed(app, event)
   %================sample===============================%
   % txt = "Run regression models first to generate recommendations.";
    % عرض داخل الـ TextArea
   % app.RecommendationsTextArea.Value = txt;
    % ⭐️ حفظ في Results
    %app.Results.Insights = txt;
   %===============sample end============================%
   % Advanced Isights: 
   %------------------>>>
  
   %=====================================================%
   %------------------------------->>>
   % old Insights and Recommendations: 
   %------------------------------->>>
% ======= Robust snippet to exclude target and build top variables =======
% assume app.SummaryTable exists and has fields:
%   - Variable (cell array, string array, or categorical)
%   - CorrWithTarget numeric vector
% and app.Target.Value contains the name of the target (char or string)

% Convert target name to char
targetVar = char(app.TargetDropDown.Value);

% Read variable names from SummaryTable and convert to cellchars
varsRaw = app.SummaryTable.Variable;

if isstring(varsRaw) || iscategorical(varsRaw)
    varsCell = cellstr(varsRaw);
elseif iscell(varsRaw)
    % ensure each is char
    varsCell = cellfun(@char, varsRaw, 'UniformOutput', false);
else
    % fallback: try to convert
    varsCell = cellstr(string(varsRaw));
end

% Read corresponding corr values
corrVals = app.SummaryTable.CorrWithTarget;
if ~isnumeric(corrVals) || numel(corrVals) ~= numel(varsCell)
    error('SummaryTable.CorrWithTarget must be numeric and match variable count.');
end

% Build table and exclude the target itself (case-insensitive)
maskNotTarget = ~strcmpi(varsCell, targetVar);
varsFiltered = varsCell(maskNotTarget);
corrFiltered = corrVals(maskNotTarget);

% If nothing left after exclusion -> notify user and exit gracefully
if isempty(varsFiltered)
    app.RecommendationsTextArea.Value = {['No correlated variables found (all variables excluded or only target present).']};
    return;
end

% Sort by absolute correlation descending
[~, idxSort] = sort(abs(corrFiltered), 'descend');
varsSorted = varsFiltered(idxSort);
corrSorted = corrFiltered(idxSort);

% pick top N (e.g., 10) but limited by available variables
N = min(10, numel(varsSorted));
topVars = varsSorted(1:N);
topCorrs = corrSorted(1:N);

% Now build the textual recommendation (plain text)
lines = {};
lines{end+1} = '📊 INSIGHTS & RECOMMENDATIONS';
lines{end+1} = '';
lines{end+1} = sprintf('The analysis identified the following variables as most correlated with the target (%s):', targetVar);

for k=1:N
    lines{end+1} = sprintf('   • %s (corr = %.2f)', topVars{k}, topCorrs(k));
end

lines{end+1} = '';
lines{end+1} = sprintf('KEY INSIGHT:');
lines{end+1} = sprintf('   → Variable %s shows the strongest correlation (%.2f).', topVars{1}, topCorrs(1));
lines{end+1} = '';

lines{end+1} = '💡 RECOMMENDATION:';
lines{end+1} = '   Focus on these variables in predictive modeling, and consider advanced regression or feature-importance analysis.';
lines{end+1} = '';
lines{end+1} = '────────────────────────────────────────────';

lines{end+1} = sprintf('Generated automatically on %s', string(datetime('now','Format','dd-MMM-yyyy HH:mm:ss')));

% Display in either RecommendationsTextArea (or RecommendationsHTML if you use HTML)

if isprop(app, 'RecommendationsTextArea') && ~isempty(app.RecommendationsTextArea)
    app.RecommendationsTextArea.Value = lines;
elseif isprop(app, 'RecommendationsHTML') && ~isempty(app.RecommendationsHTML)
   
    % simple fallback: convert lines to minimal HTML
    htmlText = ['<html><body style="font-family:Segoe UI;color:#222;padding:10px;">'];
    
    for i=1:numel(lines)
        htmlText = htmlText + string('<div>') + lines{i} + string('</div>');
    end
    
    htmlText = htmlText + '</body></html>';
    app.RecommendationsHTML.HTMLSource = char(htmlText);
else
    % fallback to Command Window
    disp(strjoin(lines, newline));
end
% ======= end snippet =======
%----------------------------------------------------------------%

    % عرض داخل الـ TextArea
      txt = app.RecommendationsTextArea.Value; 
      app.Results.Insights = txt;
   
   % ⭐️ حفظ في Results
   %----------------------------%
   app.Results.Insights = txt;
   %----------------------------% 
            
        end

        % Button pushed function: ExportRecommendationsButton
        function ExportRecommendationsButtonPushed(app, event)
%----------------------------------------------------%         
 recText = strjoin(app.RecommendationsTextArea.Value, newline);

if isempty(strtrim(recText))
    uialert(app.UIFigure, 'No recommendations to export.', 'Export Error');
    return;
end

app.exportTextToPDF(recText, ...
                    'Recommendations.pdf', ...
                    '📌 Recommendations');
%----------------------------------------------------%               
        end

        % Button pushed function: GenerateReportButton
        function GenerateReportButtonPushed(app, event)
           %--------------------------------->>>
           %% V2 — Safe Version (Guaranteed) %%
           %--------------------------------->>>
           %--------------------------------->>>
           app.buildFullReport();
           app.ReportTextArea.Value = app.ReportBuffer;        
           %--------------------------------->>>
           %--------------------------------->>>
 
        end

        % Button pushed function: ExportReportButton
        function ExportReportButtonPushed(app, event)
          %--------------------------------->>> 
          
         %% =====================================
          % Export Regression Report (Word + PDF)
         %% =====================================

    try
        %-----------------------------------------------%
        % Step 1: Check report text exists
        %-----------------------------------------------%
        if isempty(strtrim(app.ReportBuffer))
            uialert(app.UIFigure, ...
                'No report generated yet. Click "Generate Report" first.', ...
                'Export Error');
            return;
        end

        %-----------------------------------------------%
        % Step 2: Export as WORD (.docx)
        %-----------------------------------------------%
        fileNameWord = fullfile(pwd, 'Final_Regression_Report.docx');
        app.exportTextToWord(app.ReportBuffer, fileNameWord);

        %-----------------------------------------------%
        % Step 3: Export as PDF
        %-----------------------------------------------%
        fileNamePDF = fullfile(pwd, 'Final_Regression_Report.pdf');

        import mlreportgen.dom.*;
        doc = Document(fileNamePDF, 'pdf');

        % --- Title ---
        titlePara = Paragraph('FINAL REGRESSION REPORT');
        titlePara.Bold = true;
        titlePara.FontSize = '16pt';
        titlePara.HAlign = 'center';
        append(doc, titlePara);
        append(doc, Paragraph(' '));

        % --- Date ---
        datePara = Paragraph(['Generated on: ' datestr(now)]);
        datePara.Italic = true;
        datePara.HAlign = 'center';
        append(doc, datePara);
        append(doc, Paragraph(' '));

        % --- Body: use ReportBuffer as single source ---
        parts = splitlines(string(app.ReportBuffer));
        for i = 1:numel(parts)
            lineText = strtrim(parts(i));
            if strlength(lineText) > 0

                % Section headers — make them bold
                if contains(lineText, ...
                        ["DATASET", "MODEL PERFORMANCE", ...
                         "BEST MODEL", "SUMMARY", ...
                         "RECOMMENDATIONS", "INSIGHTS"])
                    p = Paragraph(lineText);
                    p.Bold = true;
                    p.FontSize = '12pt';
                    append(doc, p);

                else
                    p = Paragraph(lineText);
                    p.FontSize = '11pt';
                    append(doc, p);
                end

                append(doc, Paragraph(' '));
            end
        end

        % --- Model Performance Table ---
        append(doc, Paragraph(' '));
        if ~isempty(app.MetricsTable.Data)
            T = cell2table(app.MetricsTable.Data, ...
                'VariableNames', app.MetricsTable.ColumnName);
            app.appendMATLABTable(doc, T);
        end

        % --- Summary Table ---
        append(doc, Paragraph(' '));
        if ~isempty(app.SummaryTable)
            app.appendMATLABTable(doc, app.SummaryTable);
        end

        % --- End of Report ---
        append(doc, Paragraph('----------------------------------------'));
        endPara = Paragraph('End of Report');
        endPara.Italic = true;
        append(doc, endPara);

        close(doc);

        %-----------------------------------------------%
        % Step 4: Open both files
        %-----------------------------------------------%
        winopen(fileNameWord);
        winopen(fileNamePDF);

        app.StatusLabel.Text = 'Report exported successfully (PDF + Word) ✔️';

    catch ME
        uialert(app.UIFigure, ...
            ['Export error: ' ME.message], ...
            'Export Error');
    end

             %--------------------------------->>> 
        end

        % Button pushed function: LoadNewDataButton
        function LoadNewDataButtonPushed(app, event)
    [file,path] = uigetfile({'*.csv;*.xlsx'},'Select New Data');
    if isequal(file,0)
        return;
    end
    
    filename = fullfile(path,file);
    
    % Read data
    app.NewData = readtable(filename);
    
    % show in table
  
    app.NewData = readtable(filename);

   % app.UITable2.Data = app.NewData{:,:};

    app.UITable2.ColumnName = app.NewData.Properties.VariableNames;
    %------------------------>>
    %---------------------------------------------------%
    % Status
    app.StatusLabel.Text = "New data loaded successfully";
    %----------------------------------------------------%
 
        end

        % Button pushed function: ImportTrainedModelButton
        function ImportTrainedModelButtonPushed(app, event)
[file,path] = uigetfile('*.mat','Select Trained Model');

if isequal(file,0)
    return
end

 
S = load(fullfile(path,file));

app.TrainedModel = S.bestModelObj;
%app.FeatureNames     = S.features;
%app.TargetName       = S.target;


%----------------------------------------------------%
app.StatusLabel.Text = "Model imported successfully";
%----------------------------------------------------%   
            
disp(class(app.TrainedModel))


        end

        % Button pushed function: PredictNewDataButton
        function PredictNewDataButtonPushed(app, event)
       % التأكد من وجود البيانات
if isempty(app.NewData)
    uialert(app.UIFigure,'Load new data first','Error')
    return
end

% تجهيز البيانات بنفس features التدريب
Xnew = app.NewData(:,app.FeatureNames);

% تحويلها إلى matrix إذا لزم
if istable(Xnew)
    Xnew = table2array(Xnew);
end
disp(class(app.TrainedModel))


% prediction
app.yPred = predict(app.TrainedModel , Xnew);

% إضافة النتائج
app.NewData.Prediction = app.yPred;

% عرضها
app.UITable2.Data = app.NewData;

% رسم النتائج
plot(app.UIAxes4,app.yPred,'o')

title(app.UIAxes4,"Prediction Results")

%----------------------------------------------------%
app.StatusLabel.Text = "Prediction completed successfully";
%----------------------------------------------------%
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataCorrelationTab = uitab(app.TabGroup);
            app.DataCorrelationTab.Title = 'Data & Correlation';
            app.FilePanel = uipanel(app.DataCorrelationTab);
            app.FilePanel.Title = 'File Panel';
            app.LoadFileButton = uibutton(app.FilePanel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.TargetLabel = uilabel(app.FilePanel);
            app.TargetLabel.Text = 'Target ';
            app.TargetDropDown = uidropdown(app.FilePanel);
            app.TargetDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetDropDownValueChanged, true);
            app.StatsPanel = uipanel(app.FilePanel);
            app.StatsPanel.Title = 'Descriptive Statistics';
            app.StatsTable = uitable(app.StatsPanel);
            app.StatsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.StatsTable.RowName = {};
            app.ShowStatsButton = uibutton(app.StatsPanel, 'push');
            app.ShowStatsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowStatsButtonPushed, true);
            app.ShowStatsButton.Text = 'Show Stats';
            app.PlotAllButton = uibutton(app.StatsPanel, 'push');
            app.PlotAllButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAllButtonPushed, true);
            app.PlotAllButton.Text = 'Plot All';
            app.ShowCorrelationButton = uibutton(app.FilePanel, 'push');
            app.ShowCorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCorrelationButtonPushed, true);
            app.ShowCorrelationButton.Text = 'Show Correlation';
            app.VariablesAnalysisTab = uitab(app.TabGroup);
            app.VariablesAnalysisTab.Title = 'Variables Analysis';
            app.PlotPanel = uipanel(app.VariablesAnalysisTab);
            app.PlotPanel.Title = 'Plot Panel';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ImportantVarsLabel = uilabel(app.PlotPanel);
            app.ImportantVarsLabel.Text = 'Important Vars ';
            app.ImportantVarsDropDown = uilistbox(app.PlotPanel);
            app.ImportantVarsDropDown.Items = {};
            app.ImportantVarsDropDown.ValueChangedFcn = createCallbackFcn(app, @ImportantVarsDropDownValueChanged, true);
            app.ImportantVarsDropDown.Value = {};
            app.PlotTypeDropDownLabel = uilabel(app.PlotPanel);
            app.PlotTypeDropDownLabel.Text = 'Plot Type';
            app.PlotTypeDropDown = uidropdown(app.PlotPanel);
            app.PlotTypeDropDown.Items = {'Scatter', 'Histogram', 'Boxplot'};
            app.PlotTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotTypeDropDownValueChanged, true);
            app.PlotTypeDropDown.Value = 'Scatter';
            app.IQR_CleaningTab = uitab(app.TabGroup);
            app.IQR_CleaningTab.Title = 'IQR_Cleaning';
            app.OutliersUIAxes = uiaxes(app.IQR_CleaningTab);
            title(app.OutliersUIAxes, 'Title')
            xlabel(app.OutliersUIAxes, 'X')
            ylabel(app.OutliersUIAxes, 'Y')
            zlabel(app.OutliersUIAxes, 'Z')
            app.OutliersUIAxes2 = uiaxes(app.IQR_CleaningTab);
            title(app.OutliersUIAxes2, 'Title')
            xlabel(app.OutliersUIAxes2, 'X')
            ylabel(app.OutliersUIAxes2, 'Y')
            zlabel(app.OutliersUIAxes2, 'Z')
            app.ComputeOutliersButton = uibutton(app.IQR_CleaningTab, 'push');
            app.ComputeOutliersButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeOutliersButtonPushed, true);
            app.ComputeOutliersButton.Text = 'Compute Outliers';
            app.OutliersUITable_1 = uitable(app.IQR_CleaningTab);
            app.OutliersUITable_1.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.OutliersUITable_1.RowName = {'manual'};
            app.OutliersUITable_1.CellSelectionCallback = createCallbackFcn(app, @OutliersUITable_1CellSelection, true);
            app.ApplyOutlierActionsButton = uibutton(app.IQR_CleaningTab, 'push');
            app.ApplyOutlierActionsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyOutlierActionsButtonPushed, true);
            app.ApplyOutlierActionsButton.Text = 'Apply Outlier Actions';
            app.CLEANERButton = uibutton(app.IQR_CleaningTab, 'push');
            app.CLEANERButton.ButtonPushedFcn = createCallbackFcn(app, @CLEANERButtonPushed, true);
            app.CLEANERButton.Text = 'CLEANER';
            app.SelectedVarDropDownLabel = uilabel(app.IQR_CleaningTab);
            app.SelectedVarDropDownLabel.Text = 'SelectedVar';
            app.SelectedVarDropDown = uidropdown(app.IQR_CleaningTab);
            app.SelectedVarDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedVarDropDownValueChanged, true);
            app.Outliers_Detection_LabTab = uitab(app.TabGroup);
            app.Outliers_Detection_LabTab.Title = 'Outliers_Detection_Lab';
            app.UIAxes2 = uiaxes(app.Outliers_Detection_LabTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.Outliers_Detection_LabTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.VarNameDropDownLabel = uilabel(app.Outliers_Detection_LabTab);
            app.VarNameDropDownLabel.Text = 'VarName';
            app.VarNameDropDown = uidropdown(app.Outliers_Detection_LabTab);
            app.VarNameDropDown.ValueChangedFcn = createCallbackFcn(app, @VarNameDropDownValueChanged, true);
            app.AnalyzeButton = uibutton(app.Outliers_Detection_LabTab, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.UITable = uitable(app.Outliers_Detection_LabTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.EditFieldLabel = uilabel(app.Outliers_Detection_LabTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.Outliers_Detection_LabTab, 'numeric');
            app.EditField.Value = 2;
            app.MethodDropDownLabel = uilabel(app.Outliers_Detection_LabTab);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.Outliers_Detection_LabTab);
            app.MethodDropDown.Items = {'Z-Score (μ ± kσ)', 'IQR (Q1 - Q3)'};
            app.MethodDropDown.Value = 'Z-Score (μ ± kσ)';
            app.DataQualityIndicator = uipanel(app.Outliers_Detection_LabTab);
            app.DataQualityIndicator.Title = 'Data Quality Indicator';
            app.OutlierLabel = uilabel(app.DataQualityIndicator);
            app.OutlierLabel.Text = 'Outlier Label';
            app.LampLabel = uilabel(app.DataQualityIndicator);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.DataQualityIndicator);
            app.StatusLabel_2 = uilabel(app.DataQualityIndicator);
            app.StatusLabel_2.Text = 'Status';
            app.ML_ModelsTab = uitab(app.TabGroup);
            app.ML_ModelsTab.Title = 'ML_Models';
            app.ML_Regretion_ModulePanel = uipanel(app.ML_ModelsTab);
            app.ML_Regretion_ModulePanel.Title = 'ML_Regretion_Module ';
            app.UIAxes_2 = uiaxes(app.ML_Regretion_ModulePanel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.ML_Regretion_ModulePanel);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.ModelDropDownLabel = uilabel(app.ML_Regretion_ModulePanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.ML_Regretion_ModulePanel);
            app.ModelDropDown.Items = {'Linear Regression', 'Support Vector Regression', 'Random Forest', 'Decision Tree', 'KNN', 'Neural Network', 'Gaussian Process Regression'};
            app.ModelDropDown.Value = 'Random Forest';
            app.UITable_2 = uitable(app.ML_Regretion_ModulePanel);
            app.UITable_2.ColumnName = {'Best Model_PredictedValues'};
            app.UITable_2.RowName = {};
            app.PredictButton = uibutton(app.ML_Regretion_ModulePanel, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.MetricsTable = uitable(app.ML_Regretion_ModulePanel);
            app.MetricsTable.ColumnName = {'Model Name'; 'RMSE'; 'MAE'; 'MSE'; 'R²'; 'rmseCV'; 'r2CV'; 'Train Time'};
            app.MetricsTable.RowName = {};
            app.TrainButton = uibutton(app.ML_Regretion_ModulePanel, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.ExportSAVEButton = uibutton(app.ML_Regretion_ModulePanel, 'push');
            app.ExportSAVEButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSAVEButtonPushed, true);
            app.ExportSAVEButton.Text = 'Export/SAVE';
            app.ExportBestModelButton = uibutton(app.ML_Regretion_ModulePanel, 'push');
            app.ExportBestModelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportBestModelButtonPushed, true);
            app.ExportBestModelButton.Text = 'Export Best Model';
            app.SelectBestButton = uibutton(app.ML_Regretion_ModulePanel, 'push');
            app.SelectBestButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBestButtonPushed, true);
            app.SelectBestButton.Text = 'Select Best';
            app.ML_RegressionLabel = uilabel(app.ML_Regretion_ModulePanel);
            app.ML_RegressionLabel.Text = 'ML_Regression';
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'Summary';
            app.Summary_Panel = uipanel(app.SummaryTab);
            app.Summary_Panel.Title = 'Summary_Panel';
            app.GenerateSummaryButton = uibutton(app.Summary_Panel, 'push');
            app.GenerateSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSummaryButtonPushed, true);
            app.GenerateSummaryButton.Text = 'Generate Summary';
            app.SummaryCorrelationInsightsLabel = uilabel(app.Summary_Panel);
            app.SummaryCorrelationInsightsLabel.Text = 'Summary & Correlation Insights';
            app.SummaryUITable = uitable(app.Summary_Panel);
            app.SummaryUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SummaryUITable.RowName = {};
            app.GeneratHeatmapButton = uibutton(app.Summary_Panel, 'push');
            app.GeneratHeatmapButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratHeatmapButtonPushed, true);
            app.GeneratHeatmapButton.Text = 'Generat Heatmap';
            app.ListBoxLabel = uilabel(app.Summary_Panel);
            app.ListBoxLabel.Text = 'List Box';
            app.VariablesListBox = uilistbox(app.Summary_Panel);
            app.VariablesListBox.Items = {};
            app.VariablesListBox.Multiselect = 'on';
            app.VariablesListBox.ValueChangedFcn = createCallbackFcn(app, @VariablesListBoxValueChanged, true);
            app.VariablesListBox.Value = {};
            app.ExportSummaryButton = uibutton(app.Summary_Panel, 'push');
            app.ExportSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSummaryButtonPushed, true);
            app.ExportSummaryButton.Text = 'Export Summary';
            app.InsightsRecommendationsTab = uitab(app.TabGroup);
            app.InsightsRecommendationsTab.Title = 'Insights&Recommendations';
            app.InsightsRecommendationsTextAreaLabel = uilabel(app.InsightsRecommendationsTab);
            app.InsightsRecommendationsTextAreaLabel.Text = 'Insights & Recommendations';
            app.RecommendationsTextArea = uitextarea(app.InsightsRecommendationsTab);
            app.GeneratRecommendationsButton = uibutton(app.InsightsRecommendationsTab, 'push');
            app.GeneratRecommendationsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratRecommendationsButtonPushed, true);
            app.GeneratRecommendationsButton.Text = 'Generat Rcommendations';
            app.RecommendationsLabel = uilabel(app.InsightsRecommendationsTab);
            app.RecommendationsLabel.Text = 'Insights & Recommendations Overview';
            app.ExportRecommendationsButton = uibutton(app.InsightsRecommendationsTab, 'push');
            app.ExportRecommendationsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportRecommendationsButtonPushed, true);
            app.ExportRecommendationsButton.Text = 'Export Recommendations';
            app.FinalReportTab = uitab(app.TabGroup);
            app.FinalReportTab.Title = 'Final Report';
            app.GenerateReportButton = uibutton(app.FinalReportTab, 'push');
            app.GenerateReportButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButtonPushed, true);
            app.GenerateReportButton.Text = 'Generate Report';
            app.ReportLabel = uilabel(app.FinalReportTab);
            app.ReportLabel.Text = 'FINAL REPORT ';
            app.ExportReportButton = uibutton(app.FinalReportTab, 'push');
            app.ExportReportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportReportButtonPushed, true);
            app.ExportReportButton.Text = 'Export Report';
            app.TextAreaLabel = uilabel(app.FinalReportTab);
            app.TextAreaLabel.Text = 'Text Area';
            app.ReportTextArea = uitextarea(app.FinalReportTab);
            app.PredictionNewDataTab = uitab(app.TabGroup);
            app.PredictionNewDataTab.Title = 'Prediction New Data';
            app.PREDICTION_NEW_DATAPanel = uipanel(app.PredictionNewDataTab);
            app.PREDICTION_NEW_DATAPanel.Title = 'PREDICTION _NEW_DATA';
            app.UIAxes4 = uiaxes(app.PREDICTION_NEW_DATAPanel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.LoadNewDataButton = uibutton(app.PREDICTION_NEW_DATAPanel, 'push');
            app.LoadNewDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNewDataButtonPushed, true);
            app.LoadNewDataButton.Text = 'Load New Data';
            app.ImportTrainedModelButton = uibutton(app.PREDICTION_NEW_DATAPanel, 'push');
            app.ImportTrainedModelButton.ButtonPushedFcn = createCallbackFcn(app, @ImportTrainedModelButtonPushed, true);
            app.ImportTrainedModelButton.Text = 'Import Trained Model';
            app.PredictNewDataButton = uibutton(app.PREDICTION_NEW_DATAPanel, 'push');
            app.PredictNewDataButton.ButtonPushedFcn = createCallbackFcn(app, @PredictNewDataButtonPushed, true);
            app.PredictNewDataButton.Text = 'Predict New Data';
            app.UITable2 = uitable(app.PREDICTION_NEW_DATAPanel);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.UITable3 = uitable(app.PREDICTION_NEW_DATAPanel);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '3M_3.png';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = '3M_2.png';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status Label';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataApp_final_report_Regressions

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end